classdef Minh < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CUOIEditField                   matlab.ui.control.NumericEditField
        GIIHNCUICAMNGXLabel             matlab.ui.control.Label
        VButton                         matlab.ui.control.Button
        ChnPhngPhpListBox               matlab.ui.control.ListBox
        ChnPhngPhpListBoxLabel          matlab.ui.control.Label
        SAISOEditField                  matlab.ui.control.NumericEditField
        SAISEditFieldLabel              matlab.ui.control.Label
        DOANCONNEditField               matlab.ui.control.NumericEditField
        SONCONNEditFieldLabel           matlab.ui.control.Label
        DAUXEditField                   matlab.ui.control.NumericEditField
        GIIHNUCAMNGXLabel               matlab.ui.control.Label
        PTVIPHANEditField               matlab.ui.control.EditField
        NHPPHNGTRNHVIPHNEditFieldLabel  matlab.ui.control.Label
        Y0EditField                     matlab.ui.control.NumericEditField
        IUKINOVOY0EditFieldLabel        matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ListBoxValueChanged(app, event)
            value = app.ChnPhngPhpListBox.Value;
            
        end

        % Button pushed function: VButton
        function VButtonPushed(app, event)
            value = app.ChnPhngPhpListBox.Value;
            if (value == "Ơ LE")
                f = app.PTVIPHANEditField.Value
                x0 = app.DAUXEditField.Value
                xn = app.CUOIEditField.Value
                y0 = app.Y0EditField.Value
                N = app.DOANCONNEditField.Value
                fxy = str2func(['@(x, y) ', f])
                [x, y] = ole(fxy, x0, xn, y0, N)
    plot(app.UIAxes , x,y)
            elseif (value == "HIỆN ẨN HÌNH THANG")
                f = app.PTVIPHANEditField.Value
                x0 = app.DAUXEditField.Value
                xn = app.CUOIEditField.Value
                y0 = app.Y0EditField.Value
                N = app.DOANCONNEditField.Value
                fxy = str2func(['@(x, y) ', f])
                e = app.SAISOEditField.Value
               [x,y] = hienanhinhthang(fxy,x0,xn,y0,N,e)
    plot(app.UIAxes , x,y)
    elseif (value == "HIỆN ẨN TRUNG ĐIỂM")
                f = app.PTVIPHANEditField.Value
                x0 = app.DAUXEditField.Value
                xn = app.CUOIEditField.Value
                y0 = app.Y0EditField.Value
                N = app.DOANCONNEditField.Value
                fxy = str2func(['@(x, y) ', f])
                [x, y] = hienantrungdiem(fxy, x0, xn, y0, N)
    plot(app.UIAxes , x,y)
            else 
                f = app.PTVIPHANEditField.Value
                x0 = app.DAUXEditField.Value
                xn = app.CUOIEditField.Value
                y0 = app.Y0EditField.Value
                N = app.DOANCONNEditField.Value
                fxy = str2func(['@(x, y) ', f])
                [x, y] = RK(fxy, x0, xn, y0, N)
    plot(app.UIAxes , x,y)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.IUKINOVOY0EditFieldLabel = uilabel(app.UIFigure);
            app.IUKINOVOY0EditFieldLabel.Text = 'ĐIỀU KIỆN ĐÀO VÀO Y(0)';
            app.Y0EditField = uieditfield(app.UIFigure, 'numeric');
            app.Y0EditField.Value = 1;
            app.NHPPHNGTRNHVIPHNEditFieldLabel = uilabel(app.UIFigure);
            app.NHPPHNGTRNHVIPHNEditFieldLabel.Text = 'NHẬP PHƯƠNG TRÌNH VI PHÂN';
            app.PTVIPHANEditField = uieditfield(app.UIFigure, 'text');
            app.PTVIPHANEditField.Value = '(1-x)*y';
            app.GIIHNUCAMNGXLabel = uilabel(app.UIFigure);
            app.GIIHNUCAMNGXLabel.Text = 'GIỚI HẠN ĐẦU  CỦA MẢNG X';
            app.DAUXEditField = uieditfield(app.UIFigure, 'numeric');
            app.SONCONNEditFieldLabel = uilabel(app.UIFigure);
            app.SONCONNEditFieldLabel.Text = 'SỐ ĐOẠN CON N';
            app.DOANCONNEditField = uieditfield(app.UIFigure, 'numeric');
            app.DOANCONNEditField.Value = 25;
            app.SAISEditFieldLabel = uilabel(app.UIFigure);
            app.SAISEditFieldLabel.Text = 'SAI SỐ';
            app.SAISOEditField = uieditfield(app.UIFigure, 'numeric');
            app.SAISOEditField.Value = 0.001;
            app.ChnPhngPhpListBoxLabel = uilabel(app.UIFigure);
            app.ChnPhngPhpListBoxLabel.Text = 'Chọn Phương Pháp';
            app.ChnPhngPhpListBox = uilistbox(app.UIFigure);
            app.ChnPhngPhpListBox.Items = {'Ơ LE', 'HIỆN ẨN HÌNH THANG', 'HIỆN ẨN TRUNG ĐIỂM', 'R-K'};
            app.ChnPhngPhpListBox.Value = 'Ơ LE';
            app.VButton = uibutton(app.UIFigure, 'push');
            app.VButton.ButtonPushedFcn = createCallbackFcn(app, @VButtonPushed, true);
            app.VButton.Text = 'Vẽ';
            app.GIIHNCUICAMNGXLabel = uilabel(app.UIFigure);
            app.GIIHNCUICAMNGXLabel.Text = 'GIỚI HẠN CUỐI CỦA MẢNG X';
            app.CUOIEditField = uieditfield(app.UIFigure, 'numeric');
            app.CUOIEditField.Value = 5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Minh

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end